classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayoutEdit              matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        LowPassFilterTab            matlab.ui.container.Tab
        GridLayoutLowPass           matlab.ui.container.GridLayout
        LowPassEquationLabel        matlab.ui.control.Label
        LowPassFilterOrderSpinner   matlab.ui.control.Spinner
        FilterOrderSpinnerLabel     matlab.ui.control.Label
        LowPassRadiusSlider         matlab.ui.control.Slider
        RadiusSliderLabel           matlab.ui.control.Label
        LowPassModeDropDown         matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        HighPassFilterTab           matlab.ui.container.Tab
        GridLayoutHighPass          matlab.ui.container.GridLayout
        HighPassEquationLabel       matlab.ui.control.Label
        HighPassFilterOrderSpinner  matlab.ui.control.Spinner
        FilterOrderSpinnerLabel_2   matlab.ui.control.Label
        HighPassRadiusSlider        matlab.ui.control.Slider
        RadiusSliderLabel_2         matlab.ui.control.Label
        HighPassModeDropDown        matlab.ui.control.DropDown
        ModeDropDownLabel_2         matlab.ui.control.Label
        BandfilterTab               matlab.ui.container.Tab
        GridLayoutBand              matlab.ui.container.GridLayout
        BandFilterOrderSpinner      matlab.ui.control.Spinner
        FilterOrderSpinnerLabel_3   matlab.ui.control.Label
        BandBandwidthSlider         matlab.ui.control.Slider
        CenterFrequencyD_0Label_2   matlab.ui.control.Label
        BandEquationLabel           matlab.ui.control.Label
        BandCenterFreqSlider        matlab.ui.control.Slider
        CenterFrequencyD_0Label     matlab.ui.control.Label
        BandModeDropDown            matlab.ui.control.DropDown
        ModeDropDownLabel_3         matlab.ui.control.Label
        NotchRejectTab              matlab.ui.container.Tab
        GridLayoutNotch             matlab.ui.container.GridLayout
        NotchEquationLabel          matlab.ui.control.Label
        NotchRadiusSlider           matlab.ui.control.Slider
        CenterFrequencyD_0Label_5   matlab.ui.control.Label
        NotchFilterOrderSpinner     matlab.ui.control.Spinner
        FilterOrderSpinnerLabel_4   matlab.ui.control.Label
        NotchVertLocSlider          matlab.ui.control.Slider
        CenterFrequencyD_0Label_4   matlab.ui.control.Label
        NotchHorizLocSlider         matlab.ui.control.Slider
        CenterFrequencyD_0Label_3   matlab.ui.control.Label
        NotchModeDropDown           matlab.ui.control.DropDown
        ModeDropDownLabel_4         matlab.ui.control.Label
        BrightnessSlider            matlab.ui.control.Slider
        BrightnessLabel             matlab.ui.control.Label
        FiltersLabel                matlab.ui.control.Label
        SaveImageButton             matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        FrequencyAxes               matlab.ui.control.UIAxes
        MainAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ImageEditorState
        PreviewImageListener
        PreviewFreqListener
    end
    
    methods (Access = private)
        
        function updatePreviewImage(app)
            img = app.ImageEditorState.PreviewImage;
            if isempty(img)
                return;
            end

            imshow(img, [0 255], 'Parent', app.MainAxes);
            axis(app.MainAxes, 'on');
            axis(app.MainAxes, 'image');
        end

        function updatePreviewFrequency(app)
            F = app.ImageEditorState.PreviewImageFreq;
            if isempty(F)
                return;
            end

            F = fftshift(F, 1);
            F = fftshift(F, 2);

            spectrum = log(1 + abs(F));

            [H, W] = size(spectrum);
            fx = (-floor(W/2) : ceil(W/2)-1) / W;
            fy = (-floor(H/2) : ceil(H/2)-1) / H;
            imagesc(app.FrequencyAxes, fx, fy, spectrum);
            
            axis(app.FrequencyAxes, 'on');
            axis(app.FrequencyAxes, 'image');
        end
        
        function setFilterMode(app)
            tabName = app.TabGroup.SelectedTab.Title;
            switch tabName
                case "Low-Pass Filter"
                    orderSpinner = app.LowPassFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel;
                    modeDropdown = app.LowPassModeDropDown;
                    
                    equation = app.LowPassEquationLabel;
                    equationRowIdx = 1;
                case "High-Pass Filter"
                    orderSpinner = app.HighPassFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel_2;
                    modeDropdown = app.HighPassModeDropDown;
                    
                    equation = app.HighPassEquationLabel;
                    equationRowIdx = 2;
                case "Bandfilter"
                    orderSpinner = app.BandFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel_3;
                    modeDropdown = app.BandModeDropDown;

                    equation = app.BandEquationLabel;
                    equationRowIdx = 3;
                case "Notch Reject"
                    orderSpinner = app.NotchFilterOrderSpinner;
                    orderLabel = app.FilterOrderSpinnerLabel_4;
                    modeDropdown = app.NotchModeDropDown;

                    equation = app.NotchEquationLabel;
                    equationRowIdx = 4;
            end

            mode = modeDropdown.Value;
            switch mode
                case "Ideal"
                    equationColIdx = 1;
                case "Gaussian"
                    equationColIdx = 2;
                case "Butterworth"
                    equationColIdx = 3;
            end

            equation.Text = Equations.FilterEquations(equationRowIdx, equationColIdx);

            orderSpinner.Visible = "off";
            orderLabel.Visible = "off";
            if mode == "Butterworth"
                orderSpinner.Visible = "on";
                orderLabel.Visible = "on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageEditorState = ImageEditorState();

            app.PreviewImageListener = addlistener( ...
                app.ImageEditorState, ...
                'PreviewImage', ...
                'PostSet', ...
                @(src, event) app.updatePreviewImage());

            app.PreviewFreqListener = addlistener( ...
                app.ImageEditorState, ...
                'PreviewImageFreq', ...
                'PostSet', ...
                @(src, event) app.updatePreviewFrequency);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile( ...
                {'*.png;*.jpg;*.jpeg;*.bmp;*.tif;*.tiff', 'Image Files'});

            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            app.ImageEditorState.loadImage(img);
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ImageEditorState.adjustBrightness(changingValue);
        end

        % Value changed function: LowPassModeDropDown
        function LowPassModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Value changed function: HighPassModeDropDown
        function HighPassModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Value changed function: BandModeDropDown
        function BandModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Value changed function: NotchModeDropDown
        function NotchModeDropDownValueChanged(app, event)
            setFilterMode(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {964, 964};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {558, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutEdit = uigridlayout(app.LeftPanel);
            app.LoadImageButton = uibutton(app.GridLayoutEdit, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.GridLayoutEdit, 'push');
            app.SaveImageButton.Text = 'Save Image';
            app.FiltersLabel = uilabel(app.GridLayoutEdit);
            app.FiltersLabel.Text = 'Filters';
            app.BrightnessLabel = uilabel(app.GridLayoutEdit);
            app.BrightnessLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.GridLayoutEdit);
            app.BrightnessSlider.Limits = [0 4];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.Value = 1;
            app.TabGroup = uitabgroup(app.GridLayoutEdit);
            app.LowPassFilterTab = uitab(app.TabGroup);
            app.LowPassFilterTab.Title = 'Low-Pass Filter';
            app.GridLayoutLowPass = uigridlayout(app.LowPassFilterTab);
            app.ModeDropDownLabel = uilabel(app.GridLayoutLowPass);
            app.ModeDropDownLabel.Interpreter = 'latex';
            app.ModeDropDownLabel.Text = 'Mode';
            app.LowPassModeDropDown = uidropdown(app.GridLayoutLowPass);
            app.LowPassModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.LowPassModeDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassModeDropDownValueChanged, true);
            app.LowPassModeDropDown.Value = 'Ideal';
            app.RadiusSliderLabel = uilabel(app.GridLayoutLowPass);
            app.RadiusSliderLabel.Interpreter = 'latex';
            app.RadiusSliderLabel.Text = 'Radius';
            app.LowPassRadiusSlider = uislider(app.GridLayoutLowPass);
            app.LowPassRadiusSlider.Limits = [0 1];
            app.FilterOrderSpinnerLabel = uilabel(app.GridLayoutLowPass);
            app.FilterOrderSpinnerLabel.Visible = 'off';
            app.FilterOrderSpinnerLabel.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.LowPassFilterOrderSpinner = uispinner(app.GridLayoutLowPass);
            app.LowPassFilterOrderSpinner.Limits = [1 Inf];
            app.LowPassFilterOrderSpinner.Visible = 'off';
            app.LowPassFilterOrderSpinner.Value = 1;
            app.LowPassEquationLabel = uilabel(app.GridLayoutLowPass);
            app.LowPassEquationLabel.Interpreter = 'latex';
            app.LowPassEquationLabel.Text = '$H(u,v)=\begin{cases}1,&D(u,v)\le D_0\\0,&D(u,v)>D_0\end{cases}$';
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High-Pass Filter';
            app.GridLayoutHighPass = uigridlayout(app.HighPassFilterTab);
            app.ModeDropDownLabel_2 = uilabel(app.GridLayoutHighPass);
            app.ModeDropDownLabel_2.Interpreter = 'latex';
            app.ModeDropDownLabel_2.Text = 'Mode';
            app.HighPassModeDropDown = uidropdown(app.GridLayoutHighPass);
            app.HighPassModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.HighPassModeDropDown.ValueChangedFcn = createCallbackFcn(app, @HighPassModeDropDownValueChanged, true);
            app.HighPassModeDropDown.Value = 'Ideal';
            app.RadiusSliderLabel_2 = uilabel(app.GridLayoutHighPass);
            app.RadiusSliderLabel_2.Interpreter = 'latex';
            app.RadiusSliderLabel_2.Text = 'Radius';
            app.HighPassRadiusSlider = uislider(app.GridLayoutHighPass);
            app.HighPassRadiusSlider.Limits = [0 1];
            app.FilterOrderSpinnerLabel_2 = uilabel(app.GridLayoutHighPass);
            app.FilterOrderSpinnerLabel_2.Visible = 'off';
            app.FilterOrderSpinnerLabel_2.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel_2.Text = 'Filter Order';
            app.HighPassFilterOrderSpinner = uispinner(app.GridLayoutHighPass);
            app.HighPassFilterOrderSpinner.Limits = [1 Inf];
            app.HighPassFilterOrderSpinner.Visible = 'off';
            app.HighPassFilterOrderSpinner.Value = 1;
            app.HighPassEquationLabel = uilabel(app.GridLayoutHighPass);
            app.HighPassEquationLabel.Interpreter = 'latex';
            app.HighPassEquationLabel.Text = '$H(u,v)=\begin{cases}0,&D(u,v)\le D_0\\1,&D(u,v)>D_0\end{cases}$';
            app.BandfilterTab = uitab(app.TabGroup);
            app.BandfilterTab.Title = 'Bandfilter';
            app.GridLayoutBand = uigridlayout(app.BandfilterTab);
            app.ModeDropDownLabel_3 = uilabel(app.GridLayoutBand);
            app.ModeDropDownLabel_3.Interpreter = 'latex';
            app.ModeDropDownLabel_3.Text = 'Mode';
            app.BandModeDropDown = uidropdown(app.GridLayoutBand);
            app.BandModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.BandModeDropDown.ValueChangedFcn = createCallbackFcn(app, @BandModeDropDownValueChanged, true);
            app.BandModeDropDown.Value = 'Ideal';
            app.CenterFrequencyD_0Label = uilabel(app.GridLayoutBand);
            app.CenterFrequencyD_0Label.Interpreter = 'latex';
            app.CenterFrequencyD_0Label.Text = {'Center Frequency'; '$D_0$'};
            app.BandCenterFreqSlider = uislider(app.GridLayoutBand);
            app.BandCenterFreqSlider.Limits = [0 1];
            app.BandEquationLabel = uilabel(app.GridLayoutBand);
            app.BandEquationLabel.Interpreter = 'latex';
            app.BandEquationLabel.Text = '$H(u,v)=\begin{cases}1,&D_0-\frac{W}{2}\le D(u,v)\le D_0+\frac{W}{2}\\0,&\text{otherwise}\end{cases}$';
            app.CenterFrequencyD_0Label_2 = uilabel(app.GridLayoutBand);
            app.CenterFrequencyD_0Label_2.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_2.Text = {'Bandwidth'; '$W$'};
            app.BandBandwidthSlider = uislider(app.GridLayoutBand);
            app.BandBandwidthSlider.Limits = [0 1];
            app.FilterOrderSpinnerLabel_3 = uilabel(app.GridLayoutBand);
            app.FilterOrderSpinnerLabel_3.Visible = 'off';
            app.FilterOrderSpinnerLabel_3.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel_3.Text = 'Filter Order';
            app.BandFilterOrderSpinner = uispinner(app.GridLayoutBand);
            app.BandFilterOrderSpinner.Limits = [1 Inf];
            app.BandFilterOrderSpinner.Visible = 'off';
            app.BandFilterOrderSpinner.Value = 1;
            app.NotchRejectTab = uitab(app.TabGroup);
            app.NotchRejectTab.Title = 'Notch Reject';
            app.GridLayoutNotch = uigridlayout(app.NotchRejectTab);
            app.ModeDropDownLabel_4 = uilabel(app.GridLayoutNotch);
            app.ModeDropDownLabel_4.Interpreter = 'latex';
            app.ModeDropDownLabel_4.Text = 'Mode';
            app.NotchModeDropDown = uidropdown(app.GridLayoutNotch);
            app.NotchModeDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.NotchModeDropDown.ValueChangedFcn = createCallbackFcn(app, @NotchModeDropDownValueChanged, true);
            app.NotchModeDropDown.Value = 'Ideal';
            app.CenterFrequencyD_0Label_3 = uilabel(app.GridLayoutNotch);
            app.CenterFrequencyD_0Label_3.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_3.Text = {'Horizontal Location'; '$u_0$'};
            app.NotchHorizLocSlider = uislider(app.GridLayoutNotch);
            app.NotchHorizLocSlider.Limits = [-1 1];
            app.CenterFrequencyD_0Label_4 = uilabel(app.GridLayoutNotch);
            app.CenterFrequencyD_0Label_4.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_4.Text = {'Vertical Location'; '$v_0$'};
            app.NotchVertLocSlider = uislider(app.GridLayoutNotch);
            app.NotchVertLocSlider.Limits = [-1 1];
            app.FilterOrderSpinnerLabel_4 = uilabel(app.GridLayoutNotch);
            app.FilterOrderSpinnerLabel_4.Visible = 'off';
            app.FilterOrderSpinnerLabel_4.Interpreter = 'latex';
            app.FilterOrderSpinnerLabel_4.Text = 'Filter Order';
            app.NotchFilterOrderSpinner = uispinner(app.GridLayoutNotch);
            app.NotchFilterOrderSpinner.Limits = [1 Inf];
            app.NotchFilterOrderSpinner.Visible = 'off';
            app.NotchFilterOrderSpinner.Value = 1;
            app.CenterFrequencyD_0Label_5 = uilabel(app.GridLayoutNotch);
            app.CenterFrequencyD_0Label_5.Interpreter = 'latex';
            app.CenterFrequencyD_0Label_5.Text = 'Radius $D_0$';
            app.NotchRadiusSlider = uislider(app.GridLayoutNotch);
            app.NotchRadiusSlider.Limits = [0 1];
            app.NotchEquationLabel = uilabel(app.GridLayoutNotch);
            app.NotchEquationLabel.Interpreter = 'latex';
            app.NotchEquationLabel.Text = '$H(u,v)=\begin{cases}0,&D_1(u,v)\le D_0\ \text{or}\ D_2(u,v)\le D_0\\1,&\text{otherwise}\end{cases}$';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainAxes = uiaxes(app.RightPanel);
            title(app.MainAxes, 'Image')
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            zlabel(app.MainAxes, 'Z')
            app.MainAxes.XAxisLocation = 'top';
            app.FrequencyAxes = uiaxes(app.RightPanel);
            title(app.FrequencyAxes, 'Image (Frequency Domain)')
            xlabel(app.FrequencyAxes, 'Horizontal Frequency (cycles/pixel)')
            ylabel(app.FrequencyAxes, 'Vertical Frequency (cycles/pixel)')
            zlabel(app.FrequencyAxes, 'Z')
            app.FrequencyAxes.XAxisLocation = 'top';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end